classdef relative_permittivity_measurement_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label                          matlab.ui.control.Label
        RealpartofpermittivityEditField  matlab.ui.control.NumericEditField
        RealpartofpermittivityEditFieldLabel  matlab.ui.control.Label
        ResonantfrequencywithsampleEditField  matlab.ui.control.NumericEditField
        ResonantfrequencywithsampleEditFieldLabel  matlab.ui.control.Label
        ResonantfrequencyofemptycavityEditField  matlab.ui.control.NumericEditField
        ResonantfrequencyofemptycavityEditFieldLabel  matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        TextAreaLabel                  matlab.ui.control.Label
        ShadeebHossainLabel            matlab.ui.control.Label
        VolumeofcavityEditField        matlab.ui.control.NumericEditField
        VolumeofcavityEditFieldLabel   matlab.ui.control.Label
        WidthofrectangularcavityEditField  matlab.ui.control.NumericEditField
        WidthofrectangularcavityEditFieldLabel  matlab.ui.control.Label
        LengthofrectangularcavityEditField  matlab.ui.control.NumericEditField
        LengthofrectangularcavityEditFieldLabel  matlab.ui.control.Label
        HeightofrectangularcavityEditField  matlab.ui.control.NumericEditField
        HeightofrectangularcavityEditFieldLabel  matlab.ui.control.Label
        VolumeofsampleEditField        matlab.ui.control.NumericEditField
        VolumeofsampleEditFieldLabel   matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        HeightofcapillaytubeEditField  matlab.ui.control.NumericEditField
        HeightofcapillaytubeEditFieldLabel  matlab.ui.control.Label
        DiameterofcapillarytubeEditField  matlab.ui.control.NumericEditField
        DiameterofcapillarytubeEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            %%% Measurement of the volume of the sample
            d_c= app.DiameterofcapillarytubeEditField.Value;                % diameter of capillary tube in m
        
            h_c=app.HeightofcapillaytubeEditField.Value;                   % height of capillary tube in m 
            v_s=3.142.*(d_c/2)^2.*h_c;                                       %  Volume of the sample
            app.VolumeofsampleEditField.Value=v_s;

            %%% Measurement of Rectangular Cavity

            h_r=app.HeightofrectangularcavityEditField.Value;                % height of the rectangular cavity
            l_r=app.LengthofrectangularcavityEditField.Value;                % length of the rectangular cavity
            w_r=app.WidthofrectangularcavityEditField.Value;                 % width of the rectangular cavity
            v_c=h_r*l_r*w_r;                                                 % Volume of the cavity

            app.VolumeofcavityEditField.Value=v_c;


            %% Resonant frequencies 

f_c=app.ResonantfrequencyofemptycavityEditField.Value                   % resonant frequency of the cavity
f_s=app.ResonantfrequencywithsampleEditField.Value                   % resonant frequency of the sample


%% Formula for the measurement of the relative permittivity

e_1=[(f_c-f_s)./(2*f_s)]*[v_c/v_s]+1 % relative permittivity of the sample
app.RealpartofpermittivityEditField.Value=e_1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiameterofcapillarytubeEditFieldLabel = uilabel(app.UIFigure);
            app.DiameterofcapillarytubeEditFieldLabel.Text = 'Diameter of capillary tube  ';
            app.DiameterofcapillarytubeEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightofcapillaytubeEditFieldLabel = uilabel(app.UIFigure);
            app.HeightofcapillaytubeEditFieldLabel.Text = 'Height of capillay tube ';
            app.HeightofcapillaytubeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.VolumeofsampleEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeofsampleEditFieldLabel.Text = 'Volume of sample ';
            app.VolumeofsampleEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightofrectangularcavityEditFieldLabel = uilabel(app.UIFigure);
            app.HeightofrectangularcavityEditFieldLabel.Text = 'Height of rectangular cavity';
            app.HeightofrectangularcavityEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthofrectangularcavityEditFieldLabel = uilabel(app.UIFigure);
            app.LengthofrectangularcavityEditFieldLabel.Text = 'Length of rectangular cavity';
            app.LengthofrectangularcavityEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthofrectangularcavityEditFieldLabel = uilabel(app.UIFigure);
            app.WidthofrectangularcavityEditFieldLabel.Text = 'Width of rectangular cavity';
            app.WidthofrectangularcavityEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolumeofcavityEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeofcavityEditFieldLabel.Text = 'Volume of cavity';
            app.VolumeofcavityEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShadeebHossainLabel = uilabel(app.UIFigure);
            app.ShadeebHossainLabel.Text = 'Shadeeb Hossain';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.ResonantfrequencyofemptycavityEditFieldLabel = uilabel(app.UIFigure);
            app.ResonantfrequencyofemptycavityEditFieldLabel.Text = 'Resonant frequency of empty cavity';
            app.ResonantfrequencyofemptycavityEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResonantfrequencywithsampleEditFieldLabel = uilabel(app.UIFigure);
            app.ResonantfrequencywithsampleEditFieldLabel.Text = 'Resonant frequency with sample ';
            app.ResonantfrequencywithsampleEditField = uieditfield(app.UIFigure, 'numeric');
            app.RealpartofpermittivityEditFieldLabel = uilabel(app.UIFigure);
            app.RealpartofpermittivityEditFieldLabel.Text = 'Real part of permittivity';
            app.RealpartofpermittivityEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Measurement of Relative Permittivity using Cavity Perturbation Technique ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = relative_permittivity_measurement_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end